classdef tanks_modelling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        information                     matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        infoTitle                       matlab.ui.control.Label
        creator                         matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        helpButton                      matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        twoTanks                        matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        twoTanksTitle                   matlab.ui.control.Label
        twoTanksTank1                   matlab.ui.control.UIAxes
        twoTanksTank2                   matlab.ui.control.UIAxes
        SalidadeflujoLabel              matlab.ui.control.Label
        twoTanksSwitch                  matlab.ui.control.Switch
        twoTanksSimButton               matlab.ui.control.Button
        LabelRiseTime11                 matlab.ui.control.Label
        LabelSettlingTime11             matlab.ui.control.Label
        LabelSettlingMin11              matlab.ui.control.Label
        LabelSettlingMax11              matlab.ui.control.Label
        LabelRiseTime1                  matlab.ui.control.Label
        LabelSettlingTime1              matlab.ui.control.Label
        LabelSettlingMin1               matlab.ui.control.Label
        LabelSettlingMax1               matlab.ui.control.Label
        LabelRiseTime22                 matlab.ui.control.Label
        LabelSettlingTime22             matlab.ui.control.Label
        LabelSettlingMin22              matlab.ui.control.Label
        LabelSettlingMax22              matlab.ui.control.Label
        LabelRiseTime2                  matlab.ui.control.Label
        LabelSettlingTime2              matlab.ui.control.Label
        LabelSettlingMin2               matlab.ui.control.Label
        LabelSettlingMax2               matlab.ui.control.Label
        FlujodeentradaEditFieldLabel    matlab.ui.control.Label
        InputQin                        matlab.ui.control.NumericEditField
        Alturadetanque1EditFieldLabel   matlab.ui.control.Label
        InputHeight1                    matlab.ui.control.NumericEditField
        Alturadetanque2EditFieldLabel   matlab.ui.control.Label
        InputHeight2                    matlab.ui.control.NumericEditField
        readetanque1EditFieldLabel      matlab.ui.control.Label
        InputArea1                      matlab.ui.control.NumericEditField
        readetanque2EditFieldLabel      matlab.ui.control.Label
        InputArea2                      matlab.ui.control.NumericEditField
        Niveldetanque1EditFieldLabel    matlab.ui.control.Label
        InputLevel1                     matlab.ui.control.NumericEditField
        Niveldetanque2EditFieldLabel    matlab.ui.control.Label
        InputLevel2                     matlab.ui.control.NumericEditField
        TiempodesimulacinEditFieldLabel  matlab.ui.control.Label
        InputSimTime                    matlab.ui.control.NumericEditField
        twoTanksPID                     matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        twoTanksPIDTItle                matlab.ui.control.Label
        twoTanksPIDAxes                 matlab.ui.control.UIAxes
        SIMULARButton                   matlab.ui.control.Button
        FlujodeentradaEditFieldLabel_2  matlab.ui.control.Label
        InputPIDQin                     matlab.ui.control.NumericEditField
        Alturatanque2EditFieldLabel     matlab.ui.control.Label
        InputPIDHeight2                 matlab.ui.control.NumericEditField
        reatanque1EditFieldLabel        matlab.ui.control.Label
        InputPIDArea1                   matlab.ui.control.NumericEditField
        reatanque2EditFieldLabel        matlab.ui.control.Label
        InputPIDArea2                   matlab.ui.control.NumericEditField
        Niveltanque1EditFieldLabel      matlab.ui.control.Label
        InputPIDLevel1                  matlab.ui.control.NumericEditField
        Setpointtanque2EditFieldLabel   matlab.ui.control.Label
        InputPIDSetpoint                matlab.ui.control.NumericEditField
        KpEditFieldLabel                matlab.ui.control.Label
        InputPIDKp                      matlab.ui.control.NumericEditField
        KiEditFieldLabel                matlab.ui.control.Label
        InputPIDKi                      matlab.ui.control.NumericEditField
        KdEditFieldLabel                matlab.ui.control.Label
        InputPIDKd                      matlab.ui.control.NumericEditField
        TiempoaperturaEditFieldLabel    matlab.ui.control.Label
        InputPIDOpenTime                matlab.ui.control.NumericEditField
        TiemposimulacinEditFieldLabel   matlab.ui.control.Label
        InputPIDSimTime                 matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function oneTankSimButtonPushed(app, event)
            
        end

        % Button pushed function: twoTanksSimButton
        function twoTanksSimButtonPushed(app, event)
            area1 = app.InputArea1.Value; % m^2
            area2 = app.InputArea2.Value; % m^2
            height1 = app.InputHeight1.Value; % m
            height2 = app.InputHeight2.Value; % m
            level1 = app.InputLevel1.Value; % m
            level2 = app.InputLevel2.Value; % m
            qin = app.InputQin.Value; % m^3/s
            qmed = qin; % m^3/s
            qout = qin; % m^3/s
            time = app.InputSimTime .Value; % s
            type = app.twoTanksSwitch.Value;

            resistance1 = (level1 - level2) / qmed; % s/m^2
            resistance2 = level2 / qout; % s/m^2
            tao1 = area1 * resistance1;
            tao2 = area2 * resistance2;
                       
            assignin('base','area1',area1);
            assignin('base','area2',area2);
            assignin('base','level1',level1);
            assignin('base','level2',level2);
            assignin('base','qin',qin);
            assignin('base','qmed',qmed);
            assignin('base','qout',qout);
            assignin('base','resistance1',resistance1);
            assignin('base','resistance2',resistance2);
            assignin('base','tao1',tao1);
            assignin('base','tao2',tao2);
            assignin('base','time',time);
            
            if type == "Turbulento"
                simulation = sim('two_coupled_tanks_turbulent');
                time = simulation.time;
                data1 = simulation.data1;
                data2 = simulation.data2;
            else
                simulation = sim('two_coupled_tanks_laminar');
                time = simulation.time;
                data1 = simulation.data1;
                data2 = simulation.data2;
            end
            
%             time = 700;
%             area1 = 3; % m^2
%             area2 = 3.5; % m^2
%             height1 = 5;  
%             height2 = 3; % m
%             level1 = 1.793; % m
%             level2 = 0.7465; % m
%             qin = 0.05; % m^3/s

            % Valores de gráfica N° 1. 
            sys1 = stepinfo(data1, time);
            RiseTime1 = sys1.RiseTime;
            SettlingTime1 = sys1.SettlingTime;
            SettlingMin1 = sys1.SettlingMin;
            SettlingMax1 = sys1.SettlingMax;
            
            app.twoTanksTank1.YLim = [0 height1];
            plot(app.twoTanksTank1, time, data1);
            app.LabelRiseTime1.Text = num2str(RiseTime1) + " s";
            app.LabelSettlingTime1.Text = num2str(SettlingTime1) + " s";
            app.LabelSettlingMin1.Text = num2str(SettlingMin1) + " m";
            app.LabelSettlingMax1.Text = num2str(SettlingMax1) + " m";
            
            % Valores de gráfica N° 2. 
            sys2 = stepinfo(data2, time);
            RiseTime2 = sys2.RiseTime;
            SettlingTime2 = sys2.SettlingTime;
            SettlingMin2 = sys2.SettlingMin;
            SettlingMax2 = sys2.SettlingMax;
            
            app.twoTanksTank2.YLim = [0 height2];
            plot(app.twoTanksTank2, time, data2);
            app.LabelRiseTime2.Text = num2str(RiseTime2) + " s";
            app.LabelSettlingTime2.Text = num2str(SettlingTime2) + " s";
            app.LabelSettlingMin2.Text = num2str(SettlingMin2) + " m";
            app.LabelSettlingMax2.Text = num2str(SettlingMax2) + " m";
        end

        % Button pushed function: SIMULARButton
        function twoTanksPIDSimButtonPushed(app, event)
            area1 = app.InputPIDArea1.Value;  % m^2
            area2 = app.InputPIDArea2.Value; % m^2
            height = app.InputPIDHeight2.Value; % m
            level1 = app.InputPIDLevel1.Value; % m
            setpoint = app.InputPIDSetpoint.Value; % m
            qin = app.InputPIDQin.Value; % m^3/s
            kp = app.InputPIDKp.Value;
            ki = app.InputPIDKi.Value;
            kd = app.InputPIDKd.Value;
            time = app.InputPIDSimTime.Value; % s
            delay = app.InputPIDOpenTime.Value; % s
            
            qmed = qin; % m^3/s
            qout = qin; % m^3/s
            resistance1 = level1 - setpoint / qin; % s/m^2
            resistance2 = setpoint / qout; % s/m^2
            tao1 = area1 * resistance1;
            tao2 = area2 * resistance2;
            
            assignin('base','area1',area1);
            assignin('base','area2',area2);
            assignin('base','level1',level1);
            assignin('base','setpoint',setpoint);
            assignin('base','qin',qin);
            assignin('base','qmed',qmed);
            assignin('base','qout',qout);
            assignin('base','resistance1',resistance1);
            assignin('base','resistance2',resistance2);
            assignin('base','tao1',tao1);
            assignin('base','tao2',tao2);
            assignin('base','time',time);
            assignin('base','kp',kp);
            assignin('base','ki',ki);
            assignin('base','kd',kd);
            assignin('base','delay',delay);
            
            simulation = sim('two_coupled_tanks_PID');
            time = simulation.time;
            data2 = simulation.data2;           
            app.twoTanksPIDAxes.YLim = [0 height];
            plot(app.twoTanksPIDAxes, time, data2);x
        end

        % Button pushed function: helpButton
        function helpButtonPushed(app, event)
            open("PGII - Manual - Inicio y uso de interfaz gráfica para modelado matemático de llenado de tanques.pdf")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.information = uitab(app.TabGroup);
            app.information.Title = 'Información';
            app.GridLayout = uigridlayout(app.information);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'Tank Filling Modeling es una aplicación creada con la finalidad de que los estudiantes que encuentren cursando las materias Sistemas de control e Instrumentación industrial'; 'puedan interactuar en ella, y desarrollar practicas con respecto al llenado de diversos modelos de tanques acoplados en serie.'; ''};
            app.infoTitle = uilabel(app.GridLayout);
            app.infoTitle.Text = 'TANK FILLING MODELING';
            app.creator = uilabel(app.GridLayout);
            app.creator.Text = {'Creado por @jilverprivera | Universidad de Investigación y Desarrollo - UDI'; '2022-2'; ''};
            app.Label2 = uilabel(app.GridLayout);
            app.Label2.Text = 'En la parte superior izquierda podrá observar los distintos ejercicios propuestos para el desarrollo de prácticas académicas.';
            app.helpButton = uibutton(app.GridLayout, 'push');
            app.helpButton.ButtonPushedFcn = createCallbackFcn(app, @helpButtonPushed, true);
            app.helpButton.Text = 'Ayuda';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'IMAGE.png';
            app.twoTanks = uitab(app.TabGroup);
            app.twoTanks.Title = 'Tanques';
            app.GridLayout3 = uigridlayout(app.twoTanks);
            app.twoTanksTitle = uilabel(app.GridLayout3);
            app.twoTanksTitle.Text = 'ANÁLISIS TRANSITORIO PARA SISTEMA DE NIVEL CON DOS TANQUES INTERCONECTADOS EN SERIE';
            app.twoTanksTank1 = uiaxes(app.GridLayout3);
            title(app.twoTanksTank1, 'Llenado de tanque 1')
            xlabel(app.twoTanksTank1, 'Tiempo (s)')
            ylabel(app.twoTanksTank1, 'Altura (m)')
            app.twoTanksTank2 = uiaxes(app.GridLayout3);
            title(app.twoTanksTank2, 'Llenado de tanque 2')
            xlabel(app.twoTanksTank2, 'Tiempo (s)')
            ylabel(app.twoTanksTank2, 'Altura (m)')
            app.SalidadeflujoLabel = uilabel(app.GridLayout3);
            app.SalidadeflujoLabel.Text = 'Salida de flujo';
            app.twoTanksSwitch = uiswitch(app.GridLayout3, 'slider');
            app.twoTanksSwitch.Items = {'Laminar', 'Turbulento'};
            app.twoTanksSwitch.Value = 'Laminar';
            app.twoTanksSimButton = uibutton(app.GridLayout3, 'push');
            app.twoTanksSimButton.ButtonPushedFcn = createCallbackFcn(app, @twoTanksSimButtonPushed, true);
            app.twoTanksSimButton.Text = 'SIMULAR';
            app.LabelRiseTime11 = uilabel(app.GridLayout3);
            app.LabelRiseTime11.Text = 'Tiempo subida';
            app.LabelSettlingTime11 = uilabel(app.GridLayout3);
            app.LabelSettlingTime11.Text = 'Tiempo asentamiento';
            app.LabelSettlingMin11 = uilabel(app.GridLayout3);
            app.LabelSettlingMin11.Text = 'Ajuste mínimo';
            app.LabelSettlingMax11 = uilabel(app.GridLayout3);
            app.LabelSettlingMax11.Text = 'Ajuste máximo';
            app.LabelRiseTime1 = uilabel(app.GridLayout3);
            app.LabelRiseTime1.Text = '';
            app.LabelSettlingTime1 = uilabel(app.GridLayout3);
            app.LabelSettlingTime1.Text = '';
            app.LabelSettlingMin1 = uilabel(app.GridLayout3);
            app.LabelSettlingMin1.Text = '';
            app.LabelSettlingMax1 = uilabel(app.GridLayout3);
            app.LabelSettlingMax1.Text = '';
            app.LabelRiseTime22 = uilabel(app.GridLayout3);
            app.LabelRiseTime22.Text = 'Tiempo subida';
            app.LabelSettlingTime22 = uilabel(app.GridLayout3);
            app.LabelSettlingTime22.Text = 'Tiempo asentamiento';
            app.LabelSettlingMin22 = uilabel(app.GridLayout3);
            app.LabelSettlingMin22.Text = 'Ajuste mínimo';
            app.LabelSettlingMax22 = uilabel(app.GridLayout3);
            app.LabelSettlingMax22.Text = 'Ajuste máximo';
            app.LabelRiseTime2 = uilabel(app.GridLayout3);
            app.LabelRiseTime2.Text = '';
            app.LabelSettlingTime2 = uilabel(app.GridLayout3);
            app.LabelSettlingTime2.Text = '';
            app.LabelSettlingMin2 = uilabel(app.GridLayout3);
            app.LabelSettlingMin2.Text = '';
            app.LabelSettlingMax2 = uilabel(app.GridLayout3);
            app.LabelSettlingMax2.Text = '';
            app.FlujodeentradaEditFieldLabel = uilabel(app.GridLayout3);
            app.FlujodeentradaEditFieldLabel.Text = 'Flujo de entrada';
            app.InputQin = uieditfield(app.GridLayout3, 'numeric');
            app.InputQin.Limits = [0 Inf];
            app.Alturadetanque1EditFieldLabel = uilabel(app.GridLayout3);
            app.Alturadetanque1EditFieldLabel.Text = 'Altura de tanque 1';
            app.InputHeight1 = uieditfield(app.GridLayout3, 'numeric');
            app.InputHeight1.Limits = [0 Inf];
            app.Alturadetanque2EditFieldLabel = uilabel(app.GridLayout3);
            app.Alturadetanque2EditFieldLabel.Text = 'Altura de tanque 2';
            app.InputHeight2 = uieditfield(app.GridLayout3, 'numeric');
            app.InputHeight2.Limits = [0 Inf];
            app.readetanque1EditFieldLabel = uilabel(app.GridLayout3);
            app.readetanque1EditFieldLabel.Text = 'Área de tanque 1';
            app.InputArea1 = uieditfield(app.GridLayout3, 'numeric');
            app.InputArea1.Limits = [0 Inf];
            app.readetanque2EditFieldLabel = uilabel(app.GridLayout3);
            app.readetanque2EditFieldLabel.Text = 'Área de tanque 2';
            app.InputArea2 = uieditfield(app.GridLayout3, 'numeric');
            app.InputArea2.Limits = [0 Inf];
            app.Niveldetanque1EditFieldLabel = uilabel(app.GridLayout3);
            app.Niveldetanque1EditFieldLabel.Text = 'Nivel de tanque 1';
            app.InputLevel1 = uieditfield(app.GridLayout3, 'numeric');
            app.InputLevel1.Limits = [0 Inf];
            app.Niveldetanque2EditFieldLabel = uilabel(app.GridLayout3);
            app.Niveldetanque2EditFieldLabel.Text = 'Nivel de tanque 2';
            app.InputLevel2 = uieditfield(app.GridLayout3, 'numeric');
            app.InputLevel2.Limits = [0 Inf];
            app.TiempodesimulacinEditFieldLabel = uilabel(app.GridLayout3);
            app.TiempodesimulacinEditFieldLabel.Text = 'Tiempo de simulación';
            app.InputSimTime = uieditfield(app.GridLayout3, 'numeric');
            app.InputSimTime.Limits = [0 Inf];
            app.twoTanksPID = uitab(app.TabGroup);
            app.twoTanksPID.Title = 'Tanques [Control PID]';
            app.GridLayout4 = uigridlayout(app.twoTanksPID);
            app.twoTanksPIDTItle = uilabel(app.GridLayout4);
            app.twoTanksPIDTItle.Text = 'ANÁLISIS TRANSITORIO PARA SISTEMA DE NIVEL CON DOS TANQUES INTERCONECTADOS EN SERIE APLICANDO PID A TANQUE #2';
            app.twoTanksPIDAxes = uiaxes(app.GridLayout4);
            title(app.twoTanksPIDAxes, 'Llenado de tanque 2')
            xlabel(app.twoTanksPIDAxes, 'Tiempo (s)')
            ylabel(app.twoTanksPIDAxes, 'Altura (m)')
            app.SIMULARButton = uibutton(app.GridLayout4, 'push');
            app.SIMULARButton.ButtonPushedFcn = createCallbackFcn(app, @twoTanksPIDSimButtonPushed, true);
            app.SIMULARButton.Text = 'SIMULAR';
            app.FlujodeentradaEditFieldLabel_2 = uilabel(app.GridLayout4);
            app.FlujodeentradaEditFieldLabel_2.Text = 'Flujo de entrada';
            app.InputPIDQin = uieditfield(app.GridLayout4, 'numeric');
            app.InputPIDQin.Limits = [0 Inf];
            app.Alturatanque2EditFieldLabel = uilabel(app.GridLayout4);
            app.Alturatanque2EditFieldLabel.Text = 'Altura tanque 2';
            app.InputPIDHeight2 = uieditfield(app.GridLayout4, 'numeric');
            app.InputPIDHeight2.Limits = [0 Inf];
            app.reatanque1EditFieldLabel = uilabel(app.GridLayout4);
            app.reatanque1EditFieldLabel.Text = 'Área tanque 1';
            app.InputPIDArea1 = uieditfield(app.GridLayout4, 'numeric');
            app.InputPIDArea1.Limits = [0 Inf];
            app.reatanque2EditFieldLabel = uilabel(app.GridLayout4);
            app.reatanque2EditFieldLabel.Text = 'Área tanque 2';
            app.InputPIDArea2 = uieditfield(app.GridLayout4, 'numeric');
            app.InputPIDArea2.Limits = [0 Inf];
            app.Niveltanque1EditFieldLabel = uilabel(app.GridLayout4);
            app.Niveltanque1EditFieldLabel.Text = 'Nivel tanque 1';
            app.InputPIDLevel1 = uieditfield(app.GridLayout4, 'numeric');
            app.InputPIDLevel1.Limits = [0 Inf];
            app.Setpointtanque2EditFieldLabel = uilabel(app.GridLayout4);
            app.Setpointtanque2EditFieldLabel.Text = 'Setpoint tanque 2';
            app.InputPIDSetpoint = uieditfield(app.GridLayout4, 'numeric');
            app.InputPIDSetpoint.Limits = [0 Inf];
            app.KpEditFieldLabel = uilabel(app.GridLayout4);
            app.KpEditFieldLabel.Text = 'Kp';
            app.InputPIDKp = uieditfield(app.GridLayout4, 'numeric');
            app.InputPIDKp.Limits = [0 Inf];
            app.KiEditFieldLabel = uilabel(app.GridLayout4);
            app.KiEditFieldLabel.Text = 'Ki';
            app.InputPIDKi = uieditfield(app.GridLayout4, 'numeric');
            app.InputPIDKi.Limits = [0 Inf];
            app.KdEditFieldLabel = uilabel(app.GridLayout4);
            app.KdEditFieldLabel.Text = 'Kd';
            app.InputPIDKd = uieditfield(app.GridLayout4, 'numeric');
            app.InputPIDKd.Limits = [0 Inf];
            app.TiempoaperturaEditFieldLabel = uilabel(app.GridLayout4);
            app.TiempoaperturaEditFieldLabel.Text = 'Tiempo apertura';
            app.InputPIDOpenTime = uieditfield(app.GridLayout4, 'numeric');
            app.InputPIDOpenTime.Limits = [0 Inf];
            app.TiemposimulacinEditFieldLabel = uilabel(app.GridLayout4);
            app.TiemposimulacinEditFieldLabel.Text = 'Tiempo simulación';
            app.InputPIDSimTime = uieditfield(app.GridLayout4, 'numeric');
            app.InputPIDSimTime.Limits = [0 Inf];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tanks_modelling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end